classdef LHScanpointing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        TherangeofscanforxandyLabel  matlab.ui.control.Label
        shotnumberEditField          matlab.ui.control.NumericEditField
        shotnumberEditFieldLabel     matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        stepsizeEditField            matlab.ui.control.NumericEditField
        stepsizeEditFieldLabel       matlab.ui.control.Label
        toEditField_2                matlab.ui.control.NumericEditField
        toEditField_2Label           matlab.ui.control.Label
        fromEditField_2              matlab.ui.control.NumericEditField
        fromEditField_2Label         matlab.ui.control.Label
        yscanLabel                   matlab.ui.control.Label
        toEditField                  matlab.ui.control.NumericEditField
        toEditFieldLabel             matlab.ui.control.Label
        fromEditField                matlab.ui.control.NumericEditField
        fromEditFieldLabel           matlab.ui.control.Label
        STARTButton                  matlab.ui.control.Button
        xscanLabel                   matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        ElogButton                   matlab.ui.control.Button
        byNSMirianFBrinkerLabel      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        
function h = circle(app,x,y,r)
hold(app.UIAxes,'on')
th = 0:pi/50:2*pi;
xunit = r * cos(th) + x;
yunit = r * sin(th) + y;
h = plot(app.UIAxes,xunit, yunit, "LineWidth",2,"Color",'w');

end 

function I= imag(app,x0,y0,r,I0)

hold(app.UIAxes,'off')
x=-5*r+x0:r/10:5*r+x0;
y2=-5*r+y0:r/10:5*r+y0;
y=y2'
c=@(a,b)I0*exp(-(a.^2+b.^2)/(2*r^2))
I= contourf(app.UIAxes,x, y, c(x-x0,y-y0),'EdgeColor','none');
%hold offcontourf(x,y,c(x,y),'EdgeColor','none')
end 
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            x=doocsread('XFEL.UTIL/LASERHEATER.MOTOR/P1X.LHOS0/FPOS');
            Hor=x.data;
            app.fromEditField.Value=Hor-0.5;
            app.toEditField.Value=Hor+0.5;
            y=doocsread('XFEL.UTIL/LASERHEATER.MOTOR/P1Z.LHOS0/FPOS');
            ver=y.data;
            app.fromEditField_2.Value=ver-0.5;
            app.toEditField_2.Value=ver+0.5;


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            gamma=130/0.51;
            emitx=0.38e-6/gamma;
            emity=0.38e-6/gamma;
res=doocswrite('XFEL.UTIL/OPTICS.CALC/DESIGN_TLD/SCREEN.SELECTED', 'OTRL.48.I1');
screen48I1x=doocsread('XFEL.UTIL/OPTICS.CALC/DESIGN_TLD/SCREEN.BETAX');
screen48I1y=doocsread('XFEL.UTIL/OPTICS.CALC/DESIGN_TLD/SCREEN.BETAY');
betax=screen48I1x.data;
betay=screen48I1y.data;
sigmax=sqrt(betax*emitx);
sigmay=sqrt(betay*emity);
sigma48I1=sqrt(sigmax^2+sigmay^2)*1e3;

res=doocswrite('XFEL.UTIL/OPTICS.CALC/DESIGN_TLD/SCREEN.SELECTED', 'OTRC.56.I1');
screen56I1x=doocsread('XFEL.UTIL/OPTICS.CALC/DESIGN_TLD/SCREEN.BETAX');
screen56I1y=doocsread('XFEL.UTIL/OPTICS.CALC/DESIGN_TLD/SCREEN.BETAY');
betax=screen56I1x.data;
betay=screen56I1y.data;
sigmax=sqrt(betax*emitx)
sigmay=sqrt(betay*emity)
sigma56I1=sqrt(sigmax^2+sigmay^2)*1e3;

while 1<2
add1='XFEL.LASER/UG7.CAM/OS0.31.WAIST/ROI_SPECTRUM.X.SIG';
data_sigma=doocsread(add1);
sigma=data_sigma.data;
intnesity_s=doocsread('XFEL.DIAG/LASER.PULSEENERGY/HEATER.OS1.PD2/PULSEENERGY.MEAN');
intnesity=intnesity_s.data;

y=doocsread('XFEL.UTIL/LASERHEATER.MOTOR/P1Z.LHOS0/FPOS');
ver_piont=y.data;
x=doocsread('XFEL.UTIL/LASERHEATER.MOTOR/P1X.LHOS0/FPOS');
hor_piont=x.data;

h = imag(app,hor_piont,ver_piont,sigma,intnesity)


ho_BPM48I1=doocsread('XFEL.DIAG/BPM/BPMF.48.I1/X.ALL');
hor_BPM48I1=ho_BPM48I1.data
% 
ve_BPM48I1=doocsread('XFEL.DIAG/BPM/BPMF.48.I1/Y.ALL');
ver_BPM48I1=ve_BPM48I1.data;


h2 = circle(app,hor_BPM48I1,ver_BPM48I1,sigma48I1)


ho_BPM52I1=doocsread('XFEL.DIAG/BPM/BPMF.48.I1/X.ALL');
hor_BPM52I1=ho_BPM52I1.data
% 
ve_BPM52I1=doocsread('XFEL.DIAG/BPM/BPMF.48.I1/Y.ALL');
ver_BPM52I1=ve_BPM52I1.data;


h3 = circle(app,hor_BPM52I1,ver_BPM52I1,sigma56I1)


% if 1==1   for test
% h = imag(app,0.3,0.2,1)
% h3 = circle(app,0.34,0.25,0.3)
% end 
%  txt=sprintf('LH pointing');
%  A=getframe(gcf)
%  imwrite(A.cdata, 'temporly.png');
% result_log=hlc_send_to_logbook('author', 'Mirian','title', 'Lh poiting profile ', ...
%     'text', sprintf(txt),...
%      'image', 'temporly.png')
%  
 pause(8)
end

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            

%%%%%%%%%%%%%%%%
%   LH transverse scan,
%   Mirian 30/may/2022

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%å%%%%%%%%%
clc
clear all
step=app.stepsizeEditField.Value;
nshot=app.shotnumberEditField.Value;

Hor1=app.fromEditField.Value;
Hor2=app.toEditField.Value;
Hr=Hor1:step:Hor2;

ver1=app.fromEditField_2.Value;
ver2=app.toEditField_2.Value;
Vr=ver1:step:ver2;


%

yaddres1='XFEL.FEL/XGM/XGM.2643.T9/INTENSITY.SA1.RAW.TRAIN';
lam1=doocsread('XFEL.FEL/XGM.PHOTONFLUX/XGM.2643.T9/WAVELENGTH');

yaddres2='XFEL.FEL/XGM/XGM.2595.T6/INTENSITY.RAW.TRAIN';

lam2=doocsread('XFEL.FEL/XGM.PHOTONFLUX/XGM.2595.T6/WAVELENGTH');

yaddres3='XFEL.FEL/XGM/XGM.3130.T10/INTENSITY.SA3.RAW.TRAIN';
lam3=doocsread('XFEL.FEL/XGM.PHOTONFLUX/XGM.3130.T10/WAVELENGTH');
ii=0;
for i1=1:length(Hr)

    w1=doocswrite('XFEL.UTIL/LASERHEATER.MOTOR/P1X.LHOS0/FPOS.SET', Hr(i1));
    mp=doocswrite('XFEL.UTIL/LASERHEATER.MOTOR/P1X.LHOS0/CMD',1);

    for i2=1:length(Vr)
        w2=doocswrite('XFEL.UTIL/LASERHEATER.MOTOR/P1Z.LHOS0/FPOS.SET', Vr(i2));
        mp=doocswrite('XFEL.UTIL/LASERHEATER.MOTOR/P1Z.LHOS0/CMD',1);
        
        pause(6)
        for j=1:nshot
            y=doocsread(yaddres1);
            dy1(j)=y.data;
            y=doocsread(yaddres2);
            dy2(j)=y.data;
            y=doocsread(yaddres3);
            dy3(j)=y.data;
            
            BAM2S(j)=doocsread('XFEL.FEEDBACK/FT2.LONGITUDINAL/MONITOR14/MEAN');
            %BAM1S(j)=doocsread('XFEL.FEEDBACK/FT1.LONGITUDINAL/MONITOR7/MEAN');
            BAM2(j)=BAM2S.data;
            %BAM1(j)=BAM1S.data;
            
            BCM2S(j)=doocsread('XFEL.FEEDBACK/FT2.LONGITUDINAL/MONITOR13/MEAN');
            BCM1S(j)=doocsread('XFEL.FEEDBACK/FT1.LONGITUDINAL/MONITOR13/MEAN');
            BCM2(j)=BCM2S.data;
            BCM1(j)=BCM1S.data;


            pause(0.11)
        end
        ii=ii+1;
        DY1(i1,i2)=sum(dy1)/nshot;
        DY2(i1,i2)=sum(dy2)/nshot;
        DY3(i1,i2)=sum(dy3)/nshot;
        DBCM1(i1,i2)=sum(BCM1)/nshot;
        DBCM2(i1,i2)=sum(BCM2)/nshot;
        
%         DBAM1(i1,i2)=sum(DBCM1)/10;
%         DBAM2(i1,i2)=sum(DBCM2)/10;
        clear dy dy2 dy3 BCM2 BCM1
    end
end

%%
w1=doocswrite('XFEL.UTIL/LASERHEATER.MOTOR/P1X.LHOS0/FPOS.SET', Hro);
mp=doocswrite('XFEL.UTIL/LASERHEATER.MOTOR/P1X.LHOS0/CMD',1);
w2=doocswrite('XFEL.UTIL/LASERHEATER.MOTOR/P1Z.LHOS0/FPOS.SET', ver);
mp=doocswrite('XFEL.UTIL/LASERHEATER.MOTOR/P1Z.LHOS0/CMD',1);

%%


time=datestr(now,'yyyy_mm_dd_HH_MM')
filename=sprintf('%s_LHSCAN.mat', time)

            data_SASE=struct;
            data_SASE.SASE1Intensity=DY1;
            data_SASE.SASE2Intensity=DY2;
            data_SASE.SASE3Intensity=DY3;
            data_SASE.BCM1=DBCM1;
            data_SASE.BCM2=DBCM2;
%             data_SASE.BAM1=DBAM1;
%             data_SASE.BAM2=DBAM2;
            data_SASE.Hr=Hr;
            data_SASE.Vr=Vr;
            
save(filename, 'data_SASE')

h=figure(109395885)
h.Position = [10 10 2000 1400]; 
set(gca, 'FontSize', 18)

subplot(2,3,3)
imagesc(Hr, Vr,DY3); colorbar()
ylabel('Hr(mm)');
xlabel('Vr(mm)');
set(gca, 'FontSize', 18)
title("SASE3")

subplot(2,3,1)
imagesc(Hr, Vr,DY1); colorbar()
ylabel('Hr(mm)');
xlabel('Vr(mm)');
set(gca, 'FontSize', 18)
title("SASE1")

subplot(2,3,2)
imagesc(Hr, Vr,DY2); colorbar()
ylabel('Hr(mm)');
xlabel('Vr(mm)');
set(gca, 'FontSize', 18)
title("SASE2")

subplot(2,3,4)
imagesc(Hr, Vr,DBCM1); colorbar()
ylabel('Hr(mm)');
xlabel('Vr(mm)');
set(gca, 'FontSize', 18)
title("BCM BR1")

subplot(2,3,5)
imagesc(Hr, Vr,DBCM2); colorbar()
ylabel('Hr(mm)');
xlabel('Vr(mm)');
set(gca, 'FontSize', 18)
title("BCM BR2")


  text=['data saved in:', filename]
A=getframe(gcf);
imwrite(A.cdata, 'temporly.png');
result_log=hlc_send_to_logbook('author', 'Mirian','title', 'LH: pointing scan', ...
    'text', sprintf(text), 'image', 'temporly.png')

delete('temporly.png')


% 
% 
%          
%            save(filename, 'data_SASE')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {273, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.xscanLabel = uilabel(app.LeftPanel);
            app.xscanLabel.Text = 'x scan';
            app.STARTButton = uibutton(app.LeftPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.fromEditFieldLabel = uilabel(app.LeftPanel);
            app.fromEditFieldLabel.Text = 'from';
            app.fromEditField = uieditfield(app.LeftPanel, 'numeric');
            app.toEditFieldLabel = uilabel(app.LeftPanel);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.LeftPanel, 'numeric');
            app.yscanLabel = uilabel(app.LeftPanel);
            app.yscanLabel.Text = 'y scan';
            app.fromEditField_2Label = uilabel(app.LeftPanel);
            app.fromEditField_2Label.Text = 'from';
            app.fromEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.toEditField_2Label = uilabel(app.LeftPanel);
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.stepsizeEditFieldLabel = uilabel(app.LeftPanel);
            app.stepsizeEditFieldLabel.Text = 'step size';
            app.stepsizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.stepsizeEditField.Value = 0.1;
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {''; 'LH pointing program'; ' '; ''; '** NOTE: turn off all compression feedbacks '};
            app.shotnumberEditFieldLabel = uilabel(app.LeftPanel);
            app.shotnumberEditFieldLabel.Text = 'shot number';
            app.shotnumberEditField = uieditfield(app.LeftPanel, 'numeric');
            app.shotnumberEditField.Value = 30;
            app.TherangeofscanforxandyLabel = uilabel(app.LeftPanel);
            app.TherangeofscanforxandyLabel.Text = 'The range of scan for x and y ';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'LH pointing ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.byNSMirianFBrinkerLabel = uilabel(app.RightPanel);
            app.byNSMirianFBrinkerLabel.Text = 'by N. S. Mirian & F. Brinker ';
            app.ElogButton = uibutton(app.RightPanel, 'push');
            app.ElogButton.Text = 'Elog';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LHScanpointing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end